classdef P1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        SaveButton  matlab.ui.control.Button
        LoadButton  matlab.ui.control.Button
        DOWNButton  matlab.ui.control.Button
        UPButton    matlab.ui.control.Button
        ListBox     matlab.ui.control.ListBox
        UIAxes      matlab.ui.control.UIAxes
    end

% ID: 109550194
% Name: 龍偉亮

    properties (Access = private)
        pointList;
        curPoint;
    end
    
    methods (Access = private)
        
        function plotPoints(app)
            % Clean UIAxes
            cla(app.UIAxes);
            % No need to draw if empty
            if isempty(app.pointList)
                return
            end
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, app.pointList(:, 1), app.pointList(:, 2), 'Color', 'b');
            plot(app.UIAxes, app.pointList(:, 1), app.pointList(:, 2), '.', 'MarkerSize', 15, 'Color', 'b');
            plot(app.UIAxes, app.pointList(app.curPoint, 1), app.pointList(app.curPoint, 2), 's', 'MarkerSize', 20, 'Color', 'r');
            hold(app.UIAxes, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Reading
            fn = uigetfile('*.txt');
            if fn == 0
                return
            end
            fid = fopen(fn);
            cell = textscan(fid, '%f' ,'delimiter', ' ');
            fclose(fid);
            app.pointList = transpose(reshape(cell2mat(cell), 2, []));
            
            % Show in ListBox
            app.ListBox.Items = {};
            for i=1:size(app.pointList, 1)
                app.ListBox.Items = cat(1, app.ListBox.Items(:), sprintf('(%f, %f)', app.pointList(i, 1), app.pointList(i, 2)));
            end
            app.ListBox.Value = app.ListBox.Items(1);
            app.curPoint = 1;
            plotPoints(app);
            if ~isempty(app.pointList)
                app.SaveButton.Enable = 'on';
                app.DOWNButton.Enable = 'on';
                app.UPButton.Enable = 'on';
            end
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.curPoint = find(string(app.ListBox.Items(:)) == value);
            plotPoints(app);
        end

        % Button pushed function: DOWNButton
        function DOWNButtonPushed(app, event)
            if app.curPoint == size(app.pointList, 1)
                return
            end
            temp = app.pointList;
            temp(app.curPoint+1, :) = app.pointList(app.curPoint, :);
            temp(app.curPoint, :) = app.pointList(app.curPoint+1, :);
            app.pointList = temp;
            listTemp = app.ListBox.Items;
            listTemp(app.curPoint) = app.ListBox.Items(app.curPoint+1);
            listTemp(app.curPoint+1) = app.ListBox.Items(app.curPoint);
            app.ListBox.Items = listTemp;
            app.curPoint = app.curPoint+1;
            plotPoints(app);
        end

        % Button pushed function: UPButton
        function UPButtonPushed(app, event)
            if app.curPoint == 1
                return
            end
            temp = app.pointList;
            temp(app.curPoint-1, :) = app.pointList(app.curPoint, :);
            temp(app.curPoint, :) = app.pointList(app.curPoint-1, :);
            app.pointList = temp;
            listTemp = app.ListBox.Items;
            listTemp(app.curPoint-1) = app.ListBox.Items(app.curPoint);
            listTemp(app.curPoint) = app.ListBox.Items(app.curPoint-1);
            app.ListBox.Items = listTemp;
            app.curPoint = app.curPoint-1;
            plotPoints(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.UPButton = uibutton(app.UIFigure, 'push');
            app.UPButton.ButtonPushedFcn = createCallbackFcn(app, @UPButtonPushed, true);
            app.UPButton.Enable = 'off';
            app.UPButton.Text = 'UP';
            app.DOWNButton = uibutton(app.UIFigure, 'push');
            app.DOWNButton.ButtonPushedFcn = createCallbackFcn(app, @DOWNButtonPushed, true);
            app.DOWNButton.Enable = 'off';
            app.DOWNButton.Text = 'DOWN';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end