classdef P4_109550194 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        DeleteButton      matlab.ui.control.Button
        ChangeButton      matlab.ui.control.Button
        AddButton         matlab.ui.control.Button
        y2EditField       matlab.ui.control.NumericEditField
        y2EditFieldLabel  matlab.ui.control.Label
        x2EditField       matlab.ui.control.NumericEditField
        x2EditFieldLabel  matlab.ui.control.Label
        y1EditField       matlab.ui.control.NumericEditField
        y1EditFieldLabel  matlab.ui.control.Label
        x1EditField       matlab.ui.control.NumericEditField
        x1EditFieldLabel  matlab.ui.control.Label
        ListBox           matlab.ui.control.ListBox
        UIAxes            matlab.ui.control.UIAxes
    end

    % ID: 109550194
    % Name: 龍偉亮
    properties (Access = private)
        curIndex = 0;
        rectList = [];
    end
    
    methods (Access = private)
        
        function plotAxis(app)
            % Clean UIAxes
            cla(app.UIAxes);
            % No need to draw if empty
            if app.curIndex == 0
                return
            end
            hold(app.UIAxes, 'on');
            for i = 1:size(app.rectList, 1)
                % Plot rectangles
                if i ~= app.curIndex
                    x1 = app.rectList(i, 1);
                    y1 = app.rectList(i, 2);
                    x2 = app.rectList(i, 3);
                    y2 = app.rectList(i, 4);
                    plot(app.UIAxes, [x1 x1], [y1 y2], 'b');
                    plot(app.UIAxes, [x1 x2], [y1 y1], 'b');
                    plot(app.UIAxes, [x2 x1], [y2 y2], 'b');
                    plot(app.UIAxes, [x2 x2], [y2 y1], 'b');
                end
            end
            % Highlight selection as last plot
            x1 = app.rectList(app.curIndex, 1);
            y1 = app.rectList(app.curIndex, 2);
            x2 = app.rectList(app.curIndex, 3);
            y2 = app.rectList(app.curIndex, 4);
            plot(app.UIAxes, [x1 x1], [y1 y2], 'r');
            plot(app.UIAxes, [x1 x2], [y1 y1], 'r');
            plot(app.UIAxes, [x2 x1], [y2 y2], 'r');
            plot(app.UIAxes, [x2 x2], [y2 y1], 'r');
            hold(app.UIAxes, 'off');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            % Get values
            x1 = app.x1EditField.Value;
            y1 = app.y1EditField.Value;
            x2 = app.x2EditField.Value;
            y2 = app.y2EditField.Value;
            newRect = [x1 y1 x2 y2];
            % Check for existing rectangle
            for i = 1:size(app.rectList, 1)
                if all([app.rectList(i, 1) app.rectList(i, 2) app.rectList(i, 3) app.rectList(i, 4)] == newRect)
                    disp("ERROR: Identical rectangle already exists")
                    return
                end
            end
            % Add new coords to internal list
            app.rectList = [app.rectList ; newRect];
            % Add to ListBox
            newListItem = sprintf('(%d,%d)-(%d,%d)', x1, y1, x2, y2);
            app.ListBox.Items = cat(1, app.ListBox.Items(:), newListItem);
            app.ChangeButton.Enable = "on";
            app.DeleteButton.Enable = "on";
            app.curIndex = size(app.rectList, 1);
            % ListBox selection
            app.ListBox.Value = app.ListBox.Items(app.curIndex);
            plotAxis(app);
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            % Get values
            x1 = app.x1EditField.Value;
            y1 = app.y1EditField.Value;
            x2 = app.x2EditField.Value;
            y2 = app.y2EditField.Value;
            newRect = [x1 y1 x2 y2];
            % Check for existing rectangle
            for i = 1:size(app.rectList, 1)
                if all([app.rectList(i, 1) app.rectList(i, 2) app.rectList(i, 3) app.rectList(i, 4)] == newRect)
                    disp("ERROR: Identical rectangle already exists")
                    return
                end
            end
            % Add to internal list and ListBox
            app.rectList(app.curIndex, :) = [x1 y1 x2 y2];
            app.ListBox.Items(app.curIndex) = {sprintf('(%d,%d)-(%d,%d)', x1, y1, x2, y2)};
            % ListBox selection
            app.ListBox.Value = app.ListBox.Items(app.curIndex);
            plotAxis(app);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            % Internal list and ListBox delete
            app.rectList(app.curIndex, :) = [];
            app.ListBox.Items(app.curIndex) = [];
            if app.curIndex > size(app.ListBox.Items, 1)
                % -1 if over maximum
                app.curIndex = app.curIndex - 1;
            elseif isempty(app.ListBox.Items)
                % 0 if empty
                app.curIndex = 0;
            end
            if isempty(app.ListBox.Items)
                app.ChangeButton.Enable = "off";
                app.DeleteButton.Enable = "off";
            else
                % ListBox selection
                app.ListBox.Value = app.ListBox.Items(app.curIndex);
            end
            plotAxis(app);
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            % Handling empty clicks
            if isempty(item)
                return
            end
            app.curIndex = item;
            % Show coords in EditFields
            app.x1EditField.Value = app.rectList(app.curIndex, 1);
            app.y1EditField.Value = app.rectList(app.curIndex, 2);
            app.x2EditField.Value = app.rectList(app.curIndex, 3);
            app.y2EditField.Value = app.rectList(app.curIndex, 4);
            plotAxis(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = {};
            app.x1EditFieldLabel = uilabel(app.UIFigure);
            app.x1EditFieldLabel.Text = 'x1';
            app.x1EditField = uieditfield(app.UIFigure, 'numeric');
            app.y1EditFieldLabel = uilabel(app.UIFigure);
            app.y1EditFieldLabel.Text = 'y1';
            app.y1EditField = uieditfield(app.UIFigure, 'numeric');
            app.x2EditFieldLabel = uilabel(app.UIFigure);
            app.x2EditFieldLabel.Text = 'x2';
            app.x2EditField = uieditfield(app.UIFigure, 'numeric');
            app.y2EditFieldLabel = uilabel(app.UIFigure);
            app.y2EditFieldLabel.Text = 'y2';
            app.y2EditField = uieditfield(app.UIFigure, 'numeric');
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ChangeButton = uibutton(app.UIFigure, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Enable = 'off';
            app.ChangeButton.Text = 'Change';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Enable = 'off';
            app.DeleteButton.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P4_109550194

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end