classdef lab10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ImageInfoLabel          matlab.ui.control.Label
        CurrentfolderpathLabel  matlab.ui.control.Label
        ChangefolderButton      matlab.ui.control.Button
        ListBox                 matlab.ui.control.ListBox
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        folder; % Folder path
        im0; % Selected image
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChangefolderButton
        function ChangefolderButtonPushed(app, event)
            app.folder = uigetdir;
            if app.folder == 0
                return
            end
            app.CurrentfolderpathLabel.Text = {'Current folder path:'; app.folder};
            cd(app.folder);
            files = dir('*.jpg');
            count = 1;
            for i = 1:length(files)
                if ~(files(i).isdir)
                    filenames(count) = string(files(i).name);
                    count = count + 1;
                end
            end
            app.ListBox.Items = filenames;
            app.im0 = string(strcat(app.folder, '\', app.ListBox.Items(1)));
            data = imread(app.im0);
            image(app.UIAxes, data);
            [row, col] = size(data, [1, 2]);
            app.ImageInfoLabel.Text = string(strcat(string(row), 'x', string(col)));
        end

        % Value changed function: ListBox
        function select_file(app, event)
            value = app.ListBox.Value;
            app.im0 = string(strcat(app.folder, '\', value));
            data = imread(app.im0);
            image(app.UIAxes, data);
            [row, col] = size(data, [1, 2]);
            app.ImageInfoLabel.Text = string(strcat(string(row), 'x', string(col)));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @select_file, true);
            app.ListBox.Value = {};
            app.ChangefolderButton = uibutton(app.UIFigure, 'push');
            app.ChangefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangefolderButtonPushed, true);
            app.ChangefolderButton.Text = 'Change folder';
            app.CurrentfolderpathLabel = uilabel(app.UIFigure);
            app.CurrentfolderpathLabel.Text = {'Current folder:'; 'None'};
            app.ImageInfoLabel = uilabel(app.UIFigure);
            app.ImageInfoLabel.Text = 'Image Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end