classdef lab11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ColorResetButton        matlab.ui.control.Button
        RSlider                 matlab.ui.control.Slider
        GSlider                 matlab.ui.control.Slider
        BSlider                 matlab.ui.control.Slider
        BSliderLabel            matlab.ui.control.Label
        GSliderLabel            matlab.ui.control.Label
        RSliderLabel            matlab.ui.control.Label
        ZoomLabel               matlab.ui.control.Label
        ZoomResetButton         matlab.ui.control.Button
        ZoomOutButton           matlab.ui.control.Button
        ZoomInButton            matlab.ui.control.Button
        ImageInfoLabel          matlab.ui.control.Label
        CurrentfolderpathLabel  matlab.ui.control.Label
        ChangefolderButton      matlab.ui.control.Button
        ListBox                 matlab.ui.control.ListBox
        Panel                   matlab.ui.container.Panel
        UIAxes2                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        folder; % Folder path
        im0; % Selected image
        gamma = 1;
        rV = 0;
        gV = 0;
        bV = 0;
        zoom = 1;
    end
    
    methods (Access = private)
        
        function show_image(app)
            data = imread(app.im0);
            data = im2double(data);
            im = data;
            im(:, :, 1) = data(:, :, 1) .^ (2 ^ (-app.rV));
            im(:, :, 2) = data(:, :, 2) .^ (2 ^ (-app.gV));
            im(:, :, 3) = data(:, :, 3) .^ (2 ^ (-app.bV));
            image(app.UIAxes2, im);
            [height, width] = size(im, [1, 2]);
            app.ImageInfoLabel.Text = string(strcat(string(width), 'x', string(height)));
            app.UIAxes2.Position = [0 0 width*app.zoom height*app.zoom];
        end
        
        function adjust_enable(app)
            app.ZoomInButton.Enable = "on";
            app.ZoomOutButton.Enable = "on";
            app.ZoomResetButton.Enable = "on";
            app.ColorResetButton.Enable = "on";
            app.RSlider.Enable = "on";
            app.GSlider.Enable = "on";
            app.BSlider.Enable = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ZoomInButton.Enable = "off";
            app.ZoomOutButton.Enable = "off";
            app.ZoomResetButton.Enable = "off";
            app.ColorResetButton.Enable = "off";
            app.RSlider.Enable = "off";
            app.GSlider.Enable = "off";
            app.BSlider.Enable = "off";
        end

        % Button pushed function: ChangefolderButton
        function ChangefolderButtonPushed(app, event)
            app.folder = uigetdir;
            if app.folder == 0
                return
            end
            app.CurrentfolderpathLabel.Text = {'Current folder path:'; app.folder};
            cd(app.folder);
            files = dir('*.jpg');
            count = 1;
            for i = 1:length(files)
                if ~(files(i).isdir)
                    filenames(count) = string(files(i).name);
                    count = count + 1;
                end
            end
            app.ListBox.Items = filenames;
            app.im0 = string(strcat(app.folder, '\', app.ListBox.Items(1)));
            show_image(app);
            adjust_enable(app);
        end

        % Value changed function: ListBox
        function select_file(app, event)
            value = app.ListBox.Value;
            app.im0 = string(strcat(app.folder, '\', value));
            app.zoom = 1;
            show_image(app);
            adjust_enable(app);
        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(app, event)
            app.zoom = app.zoom * 2;
            show_image(app);
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(app, event)
            app.zoom = app.zoom / 2;
            show_image(app);
        end

        % Button pushed function: ZoomResetButton
        function ZoomResetButtonPushed(app, event)
            app.zoom = 1;
            show_image(app);
        end

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            value = app.RSlider.Value;
            app.rV = value;
            show_image(app);
        end

        % Value changed function: BSlider
        function BSliderValueChanged(app, event)
            value = app.BSlider.Value;
            app.bV = value;
            show_image(app);
        end

        % Value changed function: GSlider
        function GSliderValueChanged(app, event)
            value = app.GSlider.Value;
            app.gV = value;
            show_image(app);
        end

        % Button pushed function: ColorResetButton
        function ColorResetButtonPushed(app, event)
            app.rV = 0;
            app.gV = 0;
            app.bV = 0;
            show_image(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes2 = uiaxes(app.Panel);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @select_file, true);
            app.ListBox.Value = {};
            app.ChangefolderButton = uibutton(app.UIFigure, 'push');
            app.ChangefolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChangefolderButtonPushed, true);
            app.ChangefolderButton.Text = 'Change folder';
            app.CurrentfolderpathLabel = uilabel(app.UIFigure);
            app.CurrentfolderpathLabel.Text = {'Current folder:'; 'None'};
            app.ImageInfoLabel = uilabel(app.UIFigure);
            app.ImageInfoLabel.Text = 'Image Info';
            app.ZoomInButton = uibutton(app.UIFigure, 'push');
            app.ZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInButtonPushed, true);
            app.ZoomInButton.Text = '+';
            app.ZoomOutButton = uibutton(app.UIFigure, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
            app.ZoomOutButton.Text = '-';
            app.ZoomResetButton = uibutton(app.UIFigure, 'push');
            app.ZoomResetButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomResetButtonPushed, true);
            app.ZoomResetButton.Text = 'Reset';
            app.ZoomLabel = uilabel(app.UIFigure);
            app.ZoomLabel.Text = 'Zoom';
            app.RSliderLabel = uilabel(app.UIFigure);
            app.RSliderLabel.Text = 'R';
            app.GSliderLabel = uilabel(app.UIFigure);
            app.GSliderLabel.Text = 'G';
            app.BSliderLabel = uilabel(app.UIFigure);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.UIFigure);
            app.BSlider.Limits = [-2 2];
            app.BSlider.MajorTickLabels = {''};
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @BSliderValueChanged, true);
            app.GSlider = uislider(app.UIFigure);
            app.GSlider.Limits = [-2 2];
            app.GSlider.MajorTickLabels = {''};
            app.GSlider.ValueChangedFcn = createCallbackFcn(app, @GSliderValueChanged, true);
            app.RSlider = uislider(app.UIFigure);
            app.RSlider.Limits = [-2 2];
            app.RSlider.MajorTickLabels = {''};
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.ColorResetButton = uibutton(app.UIFigure, 'push');
            app.ColorResetButton.ButtonPushedFcn = createCallbackFcn(app, @ColorResetButtonPushed, true);
            app.ColorResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end